classdef Scrambeling_Reconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        HashTab                     matlab.ui.container.Tab
        PlayPanel                   matlab.ui.container.Panel
        HashedSoundButton           matlab.ui.control.Button
        OriginalSoundButton         matlab.ui.control.Button
        OpenHashPanel               matlab.ui.container.Panel
        CutoffKHzEditField          matlab.ui.control.NumericEditField
        CutoffKHzEditFieldLabel     matlab.ui.control.Label
        SaveHashButton              matlab.ui.control.Button
        HashButton                  matlab.ui.control.Button
        OpenAudioFileButton         matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        ReconstructionTab           matlab.ui.container.Tab
        PlayPanel_2                 matlab.ui.container.Panel
        ReconstructedButton         matlab.ui.control.Button
        HashedButton                matlab.ui.control.Button
        OpenHashedReconstructPanel  matlab.ui.container.Panel
        CutoffKHzEditField_2        matlab.ui.control.NumericEditField
        CutoffKHzEditField_2Label   matlab.ui.control.Label
        SaveReconsButton            matlab.ui.control.Button
        ReconstructButton           matlab.ui.control.Button
        OpenHashedAudioButton       matlab.ui.control.Button
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private) % Define Some New Parameters
        Button1=0;
        Button2=0;
        Button3=0;
        Button4=0;
        Button5=0;
        Button6=0;
        Button7=0;
        Button8=0;
        Button9=0;
        Button10=0;
        Sound1=0;
        Sound2=0;
        SoundCode1=0;
        SoundCode2=0;
        Fs1=0;
        Fs2=0;
    end
    
    methods (Access = private)
        
       
        function DSP0(app)% Define Main Function
        %%%%%%%%%%
        % Read Parameters (User Variables)
        Fc1 = 1000*(app.CutoffKHzEditField.Value);
        Fc2 = 1000*(app.CutoffKHzEditField_2.Value);
        M = 1000; %Filter Order
        %%%%%%%%%%
        % PushButton1
        if app.Button1 == 1
           [file1,path1] = uigetfile('*.wav');       % Get New Sound file    
           [app.Sound1 , app.Fs1] = audioread([path1 file1]);
           app.Sound1 = (app.Sound1)';
           [h1 , w1] = freqz(app.Sound1,1);
           plot(app.UIAxes,(app.Fs1)*w1/(2*pi),20*log10(abs(h1))); % Plot Frequency Response
           app.Button1 = 0;
        end 
        %%%%%%%%%%
        % PushButton2
        if app.Button2 == 1 % Code Original Signal
            %M = 120;
            n = 0 : 1 : (M-1);
            alpha = (M-1)/2;
            m = n- alpha;
            Ideal_LowPass = 2*(Fc1/app.Fs1)*sinc(2*(Fc1/app.Fs1)*m); %Define Truncated & Causal Low Pass
            Blackman = (blackman(M))'; % Blackman Design of Order M
            LowPass = Ideal_LowPass .* Blackman ; % Windowing
            app.SoundCode1 = conv(app.Sound1 , LowPass); %Filtering
            n = 0 : 1 : length(app.SoundCode1)-1 ;
            Carrier = 2*cos(2*pi*(Fc1/app.Fs1)*n); % Sample The Carrier
            app.SoundCode1 = app.SoundCode1 .* Carrier ; % Modulation
            app.SoundCode1 = conv(app.SoundCode1 , LowPass); % Filter again !
            [h1 , w1] = freqz(app.SoundCode1,1);
            plot(app.UIAxes_2,(app.Fs1)*w1/(2*pi),20*log10(abs(h1))); % Plot Freq Resp of coded Signal
            app.Button2 = 0;
        end        
        %%%%%%%%%%
        % PushButton3
        if app.Button3 == 1 %Save Hashed Audio
        audiowrite("Hashed_9723052.wav",(app.SoundCode1)',app.Fs1) ;
        app.Button3 = 0;
        end
        %%%%%%%%%%
        % PushButton4 
        if app.Button4 == 1
           sound(app.Sound1 , app.Fs1); % Play Original Audio
           app.Button4 = 0;
        end
        %%%%%%%%%%
        % PushButton5
        if app.Button5 == 1
           sound(app.SoundCode1,app.Fs1); % Play Hashed Audio
           app.Button5 = 0;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % PushButton6 
        if app.Button6 == 1 % Like above !!!
           [file2,path2] = uigetfile('*.wav');           
           [app.Sound2 , app.Fs2] = audioread([path2 file2]);
           app.Sound2 = (app.Sound2)';
           [h2 , w2] = freqz(app.Sound2,1);
           plot(app.UIAxes_3,(app.Fs2)*w2/(2*pi),20*log10(abs(h2)));
           app.Button6 = 0;
        end 
        %%%%%%%%%%
        % PushButton7
        if app.Button7 == 1
            %M = 120;                    %_%_% Like above !!! %_%_%
            n = 0 : 1 : (M-1);
            alpha = (M-1)/2;
            m = n- alpha; 
            Ideal_LowPass = 2*(Fc2/app.Fs2)*sinc(2*(Fc2/app.Fs2)*m);
            Blackman = (blackman(M))';
            LowPass = Ideal_LowPass .* Blackman ;
            app.SoundCode2 = conv(app.Sound2 , LowPass);
            n = 0 : 1 : length(app.SoundCode2)-1 ;
            Carrier = cos(2*pi*(Fc2/app.Fs2)*n);
            app.SoundCode2 = app.SoundCode2 .* Carrier ;
            app.SoundCode2 = conv(app.SoundCode2 , LowPass);
            [h2 , w2] = freqz(app.SoundCode2,1);
            plot(app.UIAxes_4,(app.Fs2)*w2/(2*pi),20*log10(abs(h2)));
            app.Button7 = 0;
        end
        %%%%%%%%%%
        % PushButton8
        if app.Button8 == 1
        audiowrite("Reconstructed_9723052.wav",(app.SoundCode2)',app.Fs2) ;
        app.Button8 = 0;
        end
        %%%%%%%%%%
        % PushButton9
        if app.Button9 == 1
           sound(app.Sound2,app.Fs2);
           app.Button9 = 0;
        end
        %%%%%%%%%%
        % PushButton10
        if app.Button10 == 1
           sound(app.SoundCode2,app.Fs2);
           app.Button10 = 0;
        end
        %%%%%%%%%%w
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DSP0(app) % Reference to Main Function
        end

        % Button pushed function: OpenAudioFileButton
        function OpenAudioFileButtonPushed2(app, event)
            app.Button1 = 1;
            DSP0(app);
        end

        % Button pushed function: HashButton
        function HashButtonPushed(app, event)
            app.Button2 = 1;
            DSP0(app);
        end

        % Button pushed function: SaveHashButton
        function SaveHashButtonPushed(app, event)
            app.Button3 = 1;
            DSP0(app);
        end

        % Button pushed function: OriginalSoundButton
        function OriginalSoundButtonPushed(app, event)
            app.Button4 = 1;
            DSP0(app);
        end

        % Button pushed function: HashedSoundButton
        function HashedSoundButtonPushed(app, event)
            app.Button5 = 1;
            DSP0(app);
        end

        % Button pushed function: OpenHashedAudioButton
        function OpenHashedAudioButtonPushed2(app, event)
            app.Button6 = 1;
            DSP0(app);
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            app.Button7 = 1;
            DSP0(app);
        end

        % Button pushed function: SaveReconsButton
        function SaveReconsButtonPushed2(app, event)
            app.Button8 = 1;
            DSP0(app);
        end

        % Button pushed function: HashedButton
        function HashedButtonPushed(app, event)
            app.Button9 = 1;
            DSP0(app);
        end

        % Button pushed function: ReconstructedButton
        function ReconstructedButtonPushed(app, event)
            app.Button10 = 1;
            DSP0(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HashTab = uitab(app.TabGroup);
            app.HashTab.Title = 'Hash';
            app.UIAxes = uiaxes(app.HashTab);
            title(app.UIAxes, 'Original Signal Spectrum')
            xlabel(app.UIAxes, 'Normalized Frequency')
            ylabel(app.UIAxes, 'Magnitude Response')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.HashTab);
            title(app.UIAxes_2, 'Hashed Signal Spectrum')
            xlabel(app.UIAxes_2, 'Normalized Frequency')
            ylabel(app.UIAxes_2, 'Magnitude Response')
            zlabel(app.UIAxes_2, 'Z')
            app.OpenHashPanel = uipanel(app.HashTab);
            app.OpenHashPanel.Title = 'Open & Hash';
            app.OpenAudioFileButton = uibutton(app.OpenHashPanel, 'push');
            app.OpenAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAudioFileButtonPushed2, true);
            app.OpenAudioFileButton.Text = 'Open Audio File';
            app.HashButton = uibutton(app.OpenHashPanel, 'push');
            app.HashButton.ButtonPushedFcn = createCallbackFcn(app, @HashButtonPushed, true);
            app.HashButton.Text = 'Hash';
            app.SaveHashButton = uibutton(app.OpenHashPanel, 'push');
            app.SaveHashButton.ButtonPushedFcn = createCallbackFcn(app, @SaveHashButtonPushed, true);
            app.SaveHashButton.Text = 'Save Hash';
            app.CutoffKHzEditFieldLabel = uilabel(app.OpenHashPanel);
            app.CutoffKHzEditFieldLabel.Text = 'Cutoff (KHz)';
            app.CutoffKHzEditField = uieditfield(app.OpenHashPanel, 'numeric');
            app.CutoffKHzEditField.Value = 10;
            app.PlayPanel = uipanel(app.HashTab);
            app.PlayPanel.Title = 'Play';
            app.OriginalSoundButton = uibutton(app.PlayPanel, 'push');
            app.OriginalSoundButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalSoundButtonPushed, true);
            app.OriginalSoundButton.Text = 'Original Sound';
            app.HashedSoundButton = uibutton(app.PlayPanel, 'push');
            app.HashedSoundButton.ButtonPushedFcn = createCallbackFcn(app, @HashedSoundButtonPushed, true);
            app.HashedSoundButton.Text = 'Hashed Sound';
            app.ReconstructionTab = uitab(app.TabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.UIAxes_3 = uiaxes(app.ReconstructionTab);
            title(app.UIAxes_3, 'Hashed Signal Spectrum')
            xlabel(app.UIAxes_3, 'Normalized Frequency')
            ylabel(app.UIAxes_3, 'Magnitude Response')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.ReconstructionTab);
            title(app.UIAxes_4, 'Reconstructed Signal Spectrum')
            xlabel(app.UIAxes_4, 'Normalized Frequency')
            ylabel(app.UIAxes_4, 'Magnitude Response')
            zlabel(app.UIAxes_4, 'Z')
            app.OpenHashedReconstructPanel = uipanel(app.ReconstructionTab);
            app.OpenHashedReconstructPanel.Title = 'Open Hashed & Reconstruct';
            app.OpenHashedAudioButton = uibutton(app.OpenHashedReconstructPanel, 'push');
            app.OpenHashedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @OpenHashedAudioButtonPushed2, true);
            app.OpenHashedAudioButton.Text = 'Open Hashed Audio ';
            app.ReconstructButton = uibutton(app.OpenHashedReconstructPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.SaveReconsButton = uibutton(app.OpenHashedReconstructPanel, 'push');
            app.SaveReconsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveReconsButtonPushed2, true);
            app.SaveReconsButton.Text = 'Save Recons';
            app.CutoffKHzEditField_2Label = uilabel(app.OpenHashedReconstructPanel);
            app.CutoffKHzEditField_2Label.Text = 'Cutoff (KHz)';
            app.CutoffKHzEditField_2 = uieditfield(app.OpenHashedReconstructPanel, 'numeric');
            app.CutoffKHzEditField_2.Value = 10;
            app.PlayPanel_2 = uipanel(app.ReconstructionTab);
            app.PlayPanel_2.Title = 'Play';
            app.HashedButton = uibutton(app.PlayPanel_2, 'push');
            app.HashedButton.ButtonPushedFcn = createCallbackFcn(app, @HashedButtonPushed, true);
            app.HashedButton.Text = 'Hashed';
            app.ReconstructedButton = uibutton(app.PlayPanel_2, 'push');
            app.ReconstructedButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructedButtonPushed, true);
            app.ReconstructedButton.Text = 'Reconstructed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scrambeling_Reconstruction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end